classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        TimeTextAreaLabel            matlab.ui.control.Label
        TimeTextArea                 matlab.ui.control.TextArea
        Panel                        matlab.ui.container.Panel
        InputfileButton              matlab.ui.control.Button
        RotatePanel                  matlab.ui.container.Panel
        RotateOnButton               matlab.ui.control.Button
        RotateOffButton              matlab.ui.control.Button
        elEditFieldLabel             matlab.ui.control.Label
        elEditField                  matlab.ui.control.NumericEditField
        ModelparameterPanel          matlab.ui.container.Panel
        ResolutionEditFieldLabel     matlab.ui.control.Label
        ResolutionEditField          matlab.ui.control.NumericEditField
        SupportRadiusEditFieldLabel  matlab.ui.control.Label
        SupportRadiusEditField       matlab.ui.control.NumericEditField
        InputAngleEditFieldLabel     matlab.ui.control.Label
        InputAngleEditField          matlab.ui.control.NumericEditField
        SupportSectionButton         matlab.ui.control.Button
        ClearSupportButton           matlab.ui.control.Button
        azEditField_2Label           matlab.ui.control.Label
        azEditField_2                matlab.ui.control.NumericEditField
        Slider                       matlab.ui.control.Slider
        AreaTextAreaLabel            matlab.ui.control.Label
        AreaTextArea                 matlab.ui.control.TextArea
        PerimeterTextAreaLabel       matlab.ui.control.Label
        PerimeterTextArea            matlab.ui.control.TextArea
        CreateSupportPanel           matlab.ui.container.Panel
        ColumnarSupportButton        matlab.ui.control.Button
        TreeSupportButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputfileButton
        function InputfileButtonPushed(app, event)
            delete(allchild(app.UIAxes));
            global HE_dege
            global face
            global vertex
            [filename]=uigetfile({'*.txt'},'Select a file');
            tic;
            %             filename='plane.txt';
            ori_data=importdata(filename);
            data_xyz=ori_data.data;
            text_data=char(ori_data.textdata);
            n=1;
            while text_data(n)=='v'
                n=n+1;
            end
            n=n-1;
            vertex=data_xyz(1:n,:);   %所有点的集合 点以序号表示
            face=data_xyz(n+1:end,:);  %所有面的集合 以面上的三个点代表面
            %             app.TimeTextArea.Value=string(['读取数据历时',num2str(toc),'秒']);
            HE_edge=new_process(face,vertex);
            fv=struct('faces',face,'vertice',vertex);
            patch(app.UIAxes,fv,'FaceColor','c');
%             axis(app.UIAxes,'equal');
            %             axis(app.UIAxes,'equal')
            view(app.UIAxes,[0 0])
            app.TimeTextArea.Value=string(['读取数据历时',num2str(toc),'秒']);
            
        end

        % Value changed function: TimeTextArea
        function TimeTextAreaValue(app, event)
            value = app.TimeTextArea.Value;
        end

        % Button pushed function: RotateOnButton
        function RotateOnButtonPushed(app, event)
            global caz1
            global cel1
            rotate3d(app.UIAxes,'on');
            app.RotateOnButton.BackgroundColor=[0.7,0.7,0.7];
            app.RotateOffButton.BackgroundColor=[1,1,1];
            [caz1,cel1] = view(app.UIAxes);
            caz1=deg2rad(caz1);
            cel1=deg2rad(cel1);
        end

        % Button pushed function: RotateOffButton
        function RotateOffButtonPushed(app, event)
            global HE_edge
            global face
            global vertex
            global caz1
            global cel1
            global caz2
            global cel2
            app.RotateOffButton.BackgroundColor=[0.7,0.7,0.7];
            app.RotateOnButton.BackgroundColor=[1,1,1];
            
            %旋转模型
            if app.azEditField_2.Value~=0||app.elEditField.Value~=0
                caz2=caz1-deg2rad(app.azEditField_2.Value);
                cel2=cel1-deg2rad(app.elEditField.Value);
            else
                [caz2,cel2]=view(app.UIAxes);
                caz2=deg2rad(caz2);
                cel2=deg2rad(cel2);
            end
            tic;
            vertex=myrotate(vertex,caz1,cel1,caz2,cel2);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;' ';'旋转模型历时',num2str(toc),'秒']);
            %改变滑块的上下限
            ML_min=min(vertex(:,3));
            ML_max=max(vertex(:,3));
            if max(vertex(:,3))<10
                ML_min_r=roundn((min(vertex(:,3))),-1);
                ML_max_r=roundn((max(vertex(:,3))),-1);
                ML_r=roundn((linspace(ML_min,ML_max,10)),-1);
            else
                ML_min_r=round((min(vertex(:,3))));
                ML_max_r=round((max(vertex(:,3))));
                ML_r=round((linspace(ML_min,ML_max,10)));
            end
            ML=linspace(ML_min,ML_max,10);
            cell_MajorLabel=cell(10,1);
            for i=1:10
                cell_MajorLabel{i}=num2str(ML_r(i));
            end
            app.Slider.MajorTickLabels=cell_MajorLabel;
            app.Slider.MajorTicks=ML';
            app.Slider.MinorTicks=linspace(ML_min,ML_max,46);
            app.Slider.Limits=[ML_min ML_max];
            %生成半边
            tic;
            HE_edge=new_process(face,vertex);
            %             fprintf('生成半边');toc
            caz2=rad2deg(caz2);cel2=rad2deg(cel2);
            caz1=rad2deg(caz1);cel1=rad2deg(cel1);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'生成半边历时',num2str(toc),'秒']);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            global face
            global vertex
            global trunk  %分辨率，根据面片的数据信息确定
            value = app.ResolutionEditField.Value;
            nface=value;%参与确定分辨率的面片数，数值与效果之间的关系大概需要试探确定，数值越大支撑越少且越大
            nface=min(nface,length(face));
            trunk=2*sum(abs(vertex(face(1:nface,1),1)-vertex(face(1:nface,2),1)))/(10*sqrt(nface))+...
                2*sum(abs(vertex(face(1:nface,1),2)-vertex(face(1:nface,2),2)))/(10*sqrt(nface));
        end

        % Value changed function: SupportRadiusEditField
        function SupportRadiusEditFieldValueChanged(app, event)
            global trunk
            global r
            value = app.SupportRadiusEditField.Value;
            nr=value;%控制支撑半径与分辨率之间的比值，nr越大支撑半径越小
            r=trunk/nr;%支撑半径
        end

        % Button pushed function: ColumnarSupportButton
        function ColumnarSupportButtonPushed(app, event)
            global r
            global vertex
            global face
            global caz2
            global cel2
            global Csp
            global Cvertex
            global Cface
            global Csp_Edge
            global caz1
            global cel1
            %求解柱状支撑区域
            tic;
            theta=(app.InputAngleEditField.Value)*pi/180;%需要生成支撑的角度
            idface_col=seedspread(theta,0);%1表示内部支撑，-1表示外部支撑，0表示全支撑
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;' ';'求解柱状支撑区域历时',num2str(toc),'秒']);
            %%
            %划分支撑区域重新采样得到支撑上端点
            spoint_col=samplep(idface_col);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'划分支撑区域重新采样得到柱状支撑上端点历时',num2str(toc),'秒']);
            %%
            %求柱状支撑下端点
            %Csp每行为[x1 y1 z1 x2 y2 z2]
            Csp=support_base(spoint_col,r);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'求柱状支撑下端点历时',num2str(toc),'秒']);
            %%
            %生成柱状支撑半边
            [Cface,Cvertex]=colsupport(Csp,r);
            Csp_Edge=new_process(Cface,Cvertex);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'生成柱状支撑半边历时',num2str(toc),'秒']);
            %%
            %画图
            %             caz2=rad2deg(caz2);cel2=rad2deg(cel2);
            delete(allchild(app.UIAxes));
            fv=struct('faces',face,'vertice',vertex);
            patch(app.UIAxes,fv,'FaceColor','c')
            %             axis(app.UIAxes,'equal')
            view(app.UIAxes,[caz1 cel1]);
            %             hold on;
            %画区域采样后在面上的点
            % for i=1:length(spoint)
            %     plot3(spoint{i}(:,2),spoint{i}(:,3),spoint{i}(:,4),'ob')
            % end
            % plot3(spoint{1}(:,2),spoint{1}(:,3),spoint{1}(:,4),'ob')
            
            %画柱状支撑
            cfv=struct('faces',Cface,'vertice',Cvertex);
            patch(app.UIAxes,cfv,'FaceColor','m','LineStyle',':')
            view(app.UIAxes,[caz1 cel1]);
            %             hold on;
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'生成柱状支撑历时',num2str(toc),'秒']);
        end

        % Value changed function: InputAngleEditField
        function InputAngleEditFieldValueChanged(app, event)
            value = app.InputAngleEditField.Value;
            
        end

        % Callback function
        function TextArea2ValueChanged(app, event)
            value = app.AreaTextArea.Value;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Button pushed function: SupportSectionButton
        function SupportSectionButtonPushed(app, event)
            global r
            global vertex
            global face
            global Csp
            global Cvertex
            global Cface
            global Csp_Edge
            global HE_edge
            global caz1
            global cel1
            tic;
            z=app.Slider.Value;
            if z<=min(vertex(:,3))
                app.TimeTextArea.Value=string([app.TimeTextArea.Value;' ';'error:z值小于范围值！']);
            elseif z>=max(vertex(:,3))
                app.TimeTextArea.Value=string([app.TimeTextArea.Value;' ';'error:z值大于范围值！']);
            else
                delete(allchild(app.UIAxes));
                [area_total,perimeter_total,fv_low,tri_cross,four_cross]=plot_cross_section(face,vertex,HE_edge,Csp,r,z,Cface,Cvertex,Csp_Edge);
                [fv_low_s,tri_cross_s,four_cross_s]=plot_cross_support(Cface,Cvertex,Csp_Edge,z);
                patch(app.UIAxes,fv_low,'FaceColor','c');
                patch(app.UIAxes,tri_cross,'FaceColor','c');
                patch(app.UIAxes,four_cross,'FaceColor','c');
                patch(app.UIAxes,fv_low_s,'FaceColor','m','LineStyle',':');
                patch(app.UIAxes,tri_cross_s,'FaceColor','m','LineStyle',':');
                patch(app.UIAxes,four_cross_s,'FaceColor','m','LineStyle',':');
                view(app.UIAxes,[135 45]);
                %                 axis(app.UIAxes,'equal');
                app.AreaTextArea.Value=string(num2str(area_total));
                app.PerimeterTextArea.Value=string(num2str(perimeter_total));
                app.TimeTextArea.Value=string([app.TimeTextArea.Value;'生成截面历时',num2str(toc),'秒']);
            end
        end

        % Value changed function: AreaTextArea
        function AreaTextAreaValueChanged(app, event)
            value = app.AreaTextArea.Value;
            
        end

        % Value changed function: PerimeterTextArea
        function PerimeterTextAreaValueChanged(app, event)
            value = app.PerimeterTextArea.Value;
            
        end

        % Button pushed function: TreeSupportButton
        function TreeSupportButtonPushed(app, event)
            global face
            global vertex
            global caz1
            global cel1
            global r
            %求解树状待支撑区域
            tic;
            theta=(app.InputAngleEditField.Value)*pi/180;flag=-1;
            idface=seedspread(theta,flag);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;' ';'求解树状支撑历时',num2str(toc),'秒']);
            %             fprintf('求解待树状支撑区域');toc
            %%
            %划分区域重新采样得到树状支撑点
            spoint=samplep(idface);
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'划分区域重新采样得到树状支撑点历时',num2str(toc),'秒']);
            %             fprintf('划分区域重新采样得到树状支撑点');toc
            %%
            %求解支撑点下的树装交点
            %tspp{i}{j}表示第i个待支撑区域的第j层支撑
            phi=pi/6;
            tspp=treesp(spoint,phi);
            %             fprintf('求解支撑点下的树装交点');toc
            %%
            %画图
            delete(allchild(app.UIAxes));
            fv=struct('faces',face,'vertice',vertex);
            patch(app.UIAxes,fv,'FaceColor','c');
            hold(app.UIAxes,'on')
            % 画生成采样点下的若干层树装支撑
            for tr=1:length(tspp)
                for i=1:length(tspp{tr})
                    plot3(app.UIAxes,tspp{tr}{i}(:,1),tspp{tr}{i}(:,2),tspp{tr}{i}(:,3),'ob')
                    hold(app.UIAxes,'on')
                end
            end
            
            % 画树状支撑
            Tsp=[];
            for tr=1:length(tspp)
                if length(tspp{tr})>1
                    for i=2:length(tspp{tr})
                        in1=tspp{tr}{i}(:,4);
                        in2=tspp{tr}{i}(:,5);
                        for plo=1:length(in1)
                            p0=tspp{tr}{i}(plo,1:3);
                            p1=tspp{tr}{i-1}(in1(plo),1:3);
                            p2=tspp{tr}{i-1}(in2(plo),1:3);
                            Tsp=[Tsp;p1 p0;p2 p0];
                        end
                        inz=linspace(1,length(tspp{tr}{i-1}(:,1)),length(tspp{tr}{i-1}(:,1)));
                        in=setdiff(inz',union(in1,in2));
                        pend=tspp{tr}{i-1}(in',1:3);
                        Tsp=[Tsp;pend pend(:,1:2) zeros(length(pend(:,1)),1)];
                    end
                    pend=tspp{tr}{i}(:,1:3);
                    Tsp=[Tsp;pend pend(:,1:2) zeros(length(pend(:,1)),1)];
                end
                if length(tspp{tr})==1
                    pend=tspp{tr}{1}(:,1:3);
                    if ~isempty(pend)
                        Tsp=[Tsp;pend pend(:,1:2) zeros(length(pend(:,1)),1)];
                    end
                end
            end
            for i=1:length(Tsp)
                [u,v,w]=cylinder(r);
                u(1,:)=u(1,:)+Tsp(i,4);u(2,:)=u(2,:)+Tsp(i,1);
                v(1,:)=v(1,:)+Tsp(i,5);v(2,:)=v(2,:)+Tsp(i,2);
                w(1,:)=w(1,:)+Tsp(i,6);w(2,:)=w(2,:)-1+Tsp(i,3);
                mesh(app.UIAxes,u,v,w);
                hold(app.UIAxes,'on')
                %                 axis(app.UIAxes,'equal')
            end
            app.TimeTextArea.Value=string([app.TimeTextArea.Value;'生成树状支撑历时',num2str(toc),'秒']);
            view(app.UIAxes,[caz1 cel1]);
        end

        % Button pushed function: ClearSupportButton
        function ClearSupportButtonPushed(app, event)
            delete(allchild(app.UIAxes));
        end

        % Value changed function: azEditField_2
        function azEditFieldValueChanged(app, event)
            value = app.azEditField_2.Value;
            
        end

        % Value changed function: elEditField
        function elEditFieldValueChanged(app, event)
            value = app.elEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ZTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTickLabel = '';
            app.TimeTextAreaLabel = uilabel(app.UIFigure);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.UIFigure);
            app.TimeTextArea.ValueChangedFcn = createCallbackFcn(app, @TimeTextAreaValue, true);
            app.TimeTextArea.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.InputfileButton = uibutton(app.Panel, 'push');
            app.InputfileButton.ButtonPushedFcn = createCallbackFcn(app, @InputfileButtonPushed, true);
            app.InputfileButton.Text = 'Input file';
            app.RotatePanel = uipanel(app.Panel);
            app.RotatePanel.Title = 'Rotate';
            app.RotateOnButton = uibutton(app.RotatePanel, 'push');
            app.RotateOnButton.ButtonPushedFcn = createCallbackFcn(app, @RotateOnButtonPushed, true);
            app.RotateOnButton.Text = 'Rotate On';
            app.RotateOffButton = uibutton(app.RotatePanel, 'push');
            app.RotateOffButton.ButtonPushedFcn = createCallbackFcn(app, @RotateOffButtonPushed, true);
            app.RotateOffButton.Text = 'Rotate Off';
            app.elEditFieldLabel = uilabel(app.RotatePanel);
            app.elEditFieldLabel.Text = 'el';
            app.elEditField = uieditfield(app.RotatePanel, 'numeric');
            app.elEditField.ValueChangedFcn = createCallbackFcn(app, @elEditFieldValueChanged, true);
            app.ModelparameterPanel = uipanel(app.Panel);
            app.ModelparameterPanel.Title = 'Model parameter';
            app.ResolutionEditFieldLabel = uilabel(app.ModelparameterPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.ModelparameterPanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.SupportRadiusEditFieldLabel = uilabel(app.ModelparameterPanel);
            app.SupportRadiusEditFieldLabel.Text = 'Support Radius';
            app.SupportRadiusEditField = uieditfield(app.ModelparameterPanel, 'numeric');
            app.SupportRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SupportRadiusEditFieldValueChanged, true);
            app.InputAngleEditFieldLabel = uilabel(app.ModelparameterPanel);
            app.InputAngleEditFieldLabel.Text = 'Input Angle';
            app.InputAngleEditField = uieditfield(app.ModelparameterPanel, 'numeric');
            app.InputAngleEditField.ValueChangedFcn = createCallbackFcn(app, @InputAngleEditFieldValueChanged, true);
            app.SupportSectionButton = uibutton(app.Panel, 'push');
            app.SupportSectionButton.ButtonPushedFcn = createCallbackFcn(app, @SupportSectionButtonPushed, true);
            app.SupportSectionButton.Text = 'Support Section';
            app.ClearSupportButton = uibutton(app.Panel, 'push');
            app.ClearSupportButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSupportButtonPushed, true);
            app.ClearSupportButton.Text = 'Clear Support';
            app.azEditField_2Label = uilabel(app.Panel);
            app.azEditField_2Label.Text = 'az';
            app.azEditField_2 = uieditfield(app.Panel, 'numeric');
            app.azEditField_2.ValueChangedFcn = createCallbackFcn(app, @azEditFieldValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AreaTextAreaLabel = uilabel(app.UIFigure);
            app.AreaTextAreaLabel.Text = 'Area';
            app.AreaTextArea = uitextarea(app.UIFigure);
            app.AreaTextArea.ValueChangedFcn = createCallbackFcn(app, @AreaTextAreaValueChanged, true);
            app.PerimeterTextAreaLabel = uilabel(app.UIFigure);
            app.PerimeterTextAreaLabel.Text = 'Perimeter';
            app.PerimeterTextArea = uitextarea(app.UIFigure);
            app.PerimeterTextArea.ValueChangedFcn = createCallbackFcn(app, @PerimeterTextAreaValueChanged, true);
            app.CreateSupportPanel = uipanel(app.UIFigure);
            app.CreateSupportPanel.Title = 'Create Support';
            app.ColumnarSupportButton = uibutton(app.CreateSupportPanel, 'push');
            app.ColumnarSupportButton.ButtonPushedFcn = createCallbackFcn(app, @ColumnarSupportButtonPushed, true);
            app.ColumnarSupportButton.Text = 'Columnar Support';
            app.TreeSupportButton = uibutton(app.CreateSupportPanel, 'push');
            app.TreeSupportButton.ButtonPushedFcn = createCallbackFcn(app, @TreeSupportButtonPushed, true);
            app.TreeSupportButton.Text = 'Tree Support';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end